classdef B10607044HW1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        Image_2             matlab.ui.control.Image
        LoadButton          matlab.ui.control.Button
        SliderLabel         matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
        Slider2Label        matlab.ui.control.Label
        Slider2             matlab.ui.control.Slider
        ZoominSpinnerLabel  matlab.ui.control.Label
        ZoominSpinner       matlab.ui.control.Spinner
        B10607044Label      matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Property % Description
        im1 %存原圖
        im1temp %存畫框的原圖
        mysize %存原圖長寬
        rect_lu = [1 1] %存方框左上角座標 預設(1,1)
    end
    
    methods (Access = public)
        
        function change(app) %改變圖案函式
            im2 = imresize(app.im1,app.ZoominSpinner.Value); %改變原圖大小
            tempsize = size(im2); %看放大後圖的長寬
            app.rect_lu(1) = round(1+(tempsize(1)-app.mysize(1))*abs(app.Slider2.Value-100)/100);%計算在右圖的方框座標點
            app.rect_lu(2) = round(1+(tempsize(2)-app.mysize(2))*app.Slider.Value/100);%計算在右圖的方框座標點
            if app.rect_lu(1)>tempsize(1)-app.mysize(1)+1||app.rect_lu(2)>tempsize(2)-app.mysize(2)+1%如果方框超出邊界
                app.rect_lu=[1 1];%調整回放大圖左上角點
                app.Slider.Value=0;%調整Slider回左上角
                app.Slider2.Value=100;%調整Slider回左上角
            end
            rimage = im2(app.rect_lu(1) : app.mysize(1)+app.rect_lu(1)-1, app.rect_lu(2):app.mysize(2)+app.rect_lu(2)-1,:);%擷取最終成像
            app.Image_2.ImageSource = rimage;%將右圖show出
            figure('visible','off');%創一個隱形figure
            imshow(app.im1)%在figure上show原圖
            hold on%要在同一個figure上畫框
            rectangle('Position',[round(1+app.rect_lu(2)/app.ZoominSpinner.Value) round(1+app.rect_lu(1)/app.ZoominSpinner.Value) round(app.mysize(2)/app.ZoominSpinner.Value)-2 round(app.mysize(1)/app.ZoominSpinner.Value)-2],'EdgeColor','r','LineWidth',3);%畫框
            hold off%關閉hold
            app.im1temp=frame2im(getframe(gcf));%將figure上畫完框的圖存下來
            close%關閉figure
            app.Image.ImageSource = app.im1temp;%將畫完框的左圖show出
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc%清command window
            app.ZoominSpinner.Enable = 0;%一開始將ZoominSpinner鎖住
            app.Slider.Enable = 0;%一開始將Slider鎖住
            app.Slider2.Enable = 0;%一開始將Slider鎖住
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname]  = uigetfile({'*.jpg';'*.png';'*.bmp';'*.tif'},'Select an image'); %顯示選檔介面
            app.im1 = imread([pathname filename]);%讀入選定檔案
            app.mysize = size(app.im1);%存原圖長寬
            app.ZoominSpinner.Enable = 1;%將ZoominSpinner解鎖
            app.Slider.Enable = 1;%將Slider解鎖
            app.Slider2.Enable = 1;%將Slider解鎖
            app.change;%show圖
        end

        % Value changed function: ZoominSpinner
        function ZoominSpinnerValueChanged(app, event)
            value = app.ZoominSpinner.Value;%將改變後的值存入變數
            if value<1 %判斷界線
                app.ZoominSpinner.Value=1; %下界線
            elseif value>7 %判斷界線
                app.ZoominSpinner.Value=7; %上界線
            end
            app.change;%show圖
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.change;%show圖
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.change;%show圖
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Value = 100;
            app.ZoominSpinnerLabel = uilabel(app.UIFigure);
            app.ZoominSpinnerLabel.Text = 'Zoom in';
            app.ZoominSpinner = uispinner(app.UIFigure);
            app.ZoominSpinner.ValueChangedFcn = createCallbackFcn(app, @ZoominSpinnerValueChanged, true);
            app.ZoominSpinner.Value = 3;
            app.B10607044Label = uilabel(app.UIFigure);
            app.B10607044Label.Text = 'B10607044';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B10607044HW1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end